classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ResultLabel            matlab.ui.control.EditField
        RESULTEditFieldLabel   matlab.ui.control.Label
        CheckButton            matlab.ui.control.Button
        EditField              matlab.ui.control.EditField
        EditFieldLabel         matlab.ui.control.Label
        Lamp                   matlab.ui.control.Lamp
        LampLabel              matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        RELAYCODELOOKUPButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CheckButton, EditField
        function ENTERButtonPushed(app, event)

 % Button pushed function: CheckButton
function CheckButtonPushed(app, event)
    % Define ANSI relay codes dictionary
    relay_codes = containers.Map( ...
        {'50', '51', '27', '59', '87', '32', '21'}, ...
        {'Instantaneous Overcurrent', ...
         'Time-Delayed Overcurrent', ...
         'Undervoltage', ...
         'Overvoltage', ...
         'Differential Protection', ...
         'Directional Power', ...
         'Distance Protection'} ...
    );

    % Convert user input to a proper string format
    relay_code = char(app.EditField.Value);  % Ensure it is a string

    % Debugging step: Print the entered value
    disp(['User entered: ', relay_code]);

    % Check if the code exists in the dictionary
    if isKey(relay_codes, relay_code)
        app.ResultLabel= sprintf('ANSI Code %s: %s', relay_code, relay_codes(relay_code));
    else
        app.ResultLabel.text= sprintf('ANSI Code %s not found.', relay_code);
    end
end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RELAYCODELOOKUPButton = uibutton(app.UIFigure, 'push');
            app.RELAYCODELOOKUPButton.Text = 'RELAY CODE LOOKUP';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Screenshot 2025-02-21 192115.png';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'EditField';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @ENTERButtonPushed, true);
            app.CheckButton = uibutton(app.UIFigure, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @ENTERButtonPushed, true);
            app.CheckButton.Text = 'CheckButton';
            app.RESULTEditFieldLabel = uilabel(app.UIFigure);
            app.RESULTEditFieldLabel.Text = 'RESULT';
            app.ResultLabel = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end